classdef Assignment_20208209 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LoadDataButton           matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        HeartRateEditFieldLabel  matlab.ui.control.Label
        HeartRateEditField       matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            S = load(fullfile(path,file));
            sig = S.x;
            
            HeartRate = (60 * 5) / 2;
                
                
             app.HeartRateEditField.Value = HeartRate;
             if (app.HeartRateEditField.Value > 120)
                app.Label.Text = "There is Myocardial Dysfunction (High Heart Rate)";
             end
             if (app.HeartRateEditField.Value < 60)
                app.Label.Text = "There is Myocardial Dysfunction (Low Heart Rate)";
             end
             if (app.HeartRateEditField.Value <= 120 && app.HeartRateEditField.Value >= 60)
                app.Label.Text = "Normal Heart Rate";
             end
            
            plot(app.UIAxes,sig)
%              xlim(app.UIAxes,[1 1000])
%              ylim(app.UIAxes,[100 600])
            xlabel(app.UIAxes,'milliseconds')
            ylabel(app.UIAxes,'millivolts')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = {'Load Data'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HeartRateEditFieldLabel = uilabel(app.UIFigure);
            app.HeartRateEditFieldLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment_20208209

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end